classdef DHConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculateDenavitHartenbergButton  matlab.ui.control.Button
        RemoveLastLinksButton          matlab.ui.control.Button
        BasiskoordinatensystemLabel    matlab.ui.control.Label
        QuationenLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DenavitHartenbergParameterTab  matlab.ui.container.Tab
        DenavitTable                   matlab.ui.control.Table
        BasiskoorsysundQuationenTab    matlab.ui.container.Tab
        KoorsysUndQuationen            matlab.ui.control.Table
        ShowPlotButton                 matlab.ui.control.Button
        AddLinksButton                 matlab.ui.control.Button
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        q2EditField                    matlab.ui.control.NumericEditField
        q2EditFieldLabel               matlab.ui.control.Label
        q3EditField                    matlab.ui.control.NumericEditField
        q3EditFieldLabel               matlab.ui.control.Label
        q1EditField                    matlab.ui.control.NumericEditField
        q1EditFieldLabel               matlab.ui.control.Label
        q0EditField                    matlab.ui.control.NumericEditField
        q0EditFieldLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Numbers_I               % Anzahl der Links
        BasisVektorsList        % Liste von Basiskoordinatensystem
        QuaternionZeroList      % Liste der q0
        QuaternionVektorList    % Lister der q vektor
        index = 0;
        InputData = [];
        OutputData = [];
        aList
        dList
        alphaList
        tethaList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddLinksButton
        function AddLinksButtonPushed(app, event)
            app.index = app.index + 1;
            % Add to the current properties
            app.BasisVektorsList(app.index,:) = [app.xEditField.Value, app.yEditField.Value, app.zEditField.Value];
            if checkPlausibilityBasiskoorsystem(app.BasisVektorsList) == 0
                warndlg("Link Objekte können nicht miteinander verbunden werden- Bitte Vektoren überprüfen und erneut eingeben!", "Warning");
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.index = app.index - 1;
                return
            end
            
            app.QuaternionZeroList(app.index) = app.q0EditField.Value;
            app.QuaternionVektorList(app.index,:) = [app.q1EditField.Value, app.q2EditField.Value, app.q3EditField.Value];
            [Quaternion_plausible, msg] = checkPlausibilityQuaternion(app.QuaternionZeroList, app.QuaternionVektorList);
            if Quaternion_plausible == 0
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.QuaternionZeroList = app.QuaternionZeroList(1:end-1,:);
                app.QuaternionVektorList = app.QuaternionVektorList(1:end-1,:);
                warndlg(msg, "Warning!");
                app.index = app.index - 1;
                return
            end
            
            app.InputData(app.index,:) = [app.BasisVektorsList(app.index,:) app.QuaternionZeroList(app.index) app.QuaternionVektorList(app.index,:)];
            app.KoorsysUndQuationen.Data = app.InputData;
        end

        % Button pushed function: RemoveLastLinksButton
        function RemoveLastLinksButtonPushed(app, event)
            if app.index == 0
                warndlg("There is no row to be remove!","Warning");
            else
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.QuaternionZeroList = app.QuaternionZeroList(1:end-1,:);
                app.QuaternionVektorList = app.QuaternionVektorList(1:end-1,:);
                app.InputData = app.InputData(1:end-1,:);
                app.KoorsysUndQuationen.Data = app.InputData;
                app.index = app.index - 1; 
            end
        end

        % Button pushed function: CalculateDenavitHartenbergButton
        function CalculateDenavitHartenbergButtonPushed(app, event)
            % do the callculation here
            if app.index == 0
                warndlg("Please input the basis coordinate and the quaternion first!","Warning");
            else
                [app.aList, app.dList, app.alphaList, app.tethaList] = calculateDenavitHartenbergParam(app.BasisVektorsList, app.QuaternionZeroList, app.QuaternionVektorList);
                app.OutputData = [app.aList, app.dList, app.alphaList, app.tethaList];
                app.DenavitTable.Data = app.OutputData; 
            end
        end

        % Button pushed function: ShowPlotButton
        function ShowPlotButtonPushed(app, event)
            if app.index < 2
                warndlg("2 Links are needed to plot!");
            else
                if isempty(app.OutputData)
                    warndlg("Calculate DH parameter first!");
                else
                    showRobotInPlot(app.tethaList, app.dList, app.aList, app.alphaList);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q0EditFieldLabel = uilabel(app.UIFigure);
            app.q0EditFieldLabel.Text = 'q0';
            app.q0EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddLinksButton = uibutton(app.UIFigure, 'push');
            app.AddLinksButton.ButtonPushedFcn = createCallbackFcn(app, @AddLinksButtonPushed, true);
            app.AddLinksButton.Text = 'Add Links';
            app.ShowPlotButton = uibutton(app.UIFigure, 'push');
            app.ShowPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlotButtonPushed, true);
            app.ShowPlotButton.Text = 'Show Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DenavitHartenbergParameterTab = uitab(app.TabGroup);
            app.DenavitHartenbergParameterTab.Title = 'Denavit Hartenberg Parameter';
            app.DenavitTable = uitable(app.DenavitHartenbergParameterTab);
            app.DenavitTable.ColumnName = {'a'; 'alpha'; 'd'; 'tetha'};
            app.DenavitTable.RowName = {};
            app.BasiskoorsysundQuationenTab = uitab(app.TabGroup);
            app.BasiskoorsysundQuationenTab.Title = 'Basiskoor.sys und Quationen';
            app.KoorsysUndQuationen = uitable(app.BasiskoorsysundQuationenTab);
            app.KoorsysUndQuationen.ColumnName = {'x'; 'y'; 'z'; 'q0'; 'q1'; 'q2'; 'q3'};
            app.KoorsysUndQuationen.RowName = {};
            app.QuationenLabel = uilabel(app.UIFigure);
            app.QuationenLabel.Text = 'Quationen';
            app.BasiskoordinatensystemLabel = uilabel(app.UIFigure);
            app.BasiskoordinatensystemLabel.Text = 'Basiskoordinatensystem';
            app.RemoveLastLinksButton = uibutton(app.UIFigure, 'push');
            app.RemoveLastLinksButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastLinksButtonPushed, true);
            app.RemoveLastLinksButton.Text = 'Remove Last Links';
            app.CalculateDenavitHartenbergButton = uibutton(app.UIFigure, 'push');
            app.CalculateDenavitHartenbergButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDenavitHartenbergButtonPushed, true);
            app.CalculateDenavitHartenbergButton.Text = 'Calculate Denavit Hartenberg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DHConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end